classdef GUI_NACA_4Digit_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        AirfoilAnalysisusingThinAirfoilTheoryAuthorDanialMoghadamLabel  matlab.ui.control.Label
        InputDesiredNACA4digitAirfoilLabel  matlab.ui.control.Label
        InputAOAAngleofAttackforthePressureDistributionLabel  matlab.ui.control.Label
        NACAEditField                  matlab.ui.control.EditField
        CpAlphaEditField               matlab.ui.control.NumericEditField
        AnalyzeButton                  matlab.ui.control.Button
        CpAngleofAttackEditFieldLabel  matlab.ui.control.Label
        NACA4DigitsEditFieldLabel      matlab.ui.control.Label
        UIAxes_Airfoil                 matlab.ui.control.UIAxes
        UIAxes_Cl                      matlab.ui.control.UIAxes
        UIAxes_Cp                      matlab.ui.control.UIAxes
        UIAxes_Cm                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 1. Standardize Airfoil Shape Axes
app.UIAxes_Airfoil.XLim = [0 1];
app.UIAxes_Airfoil.XTick = 0:0.2:1;
app.UIAxes_Airfoil.YLim = [-0.5 0.5]; % Gives breathing room above and below the airfoil
%app.UIAxes_Airfoil.YTick = -0.5:0.25:0.5;
app.UIAxes_Airfoil.XGrid = 'on';
app.UIAxes_Airfoil.YGrid = 'on';
app.UIAxes_Airfoil.DataAspectRatio = [1 1 1]; % Ensures it doesn't look stretched
title(app.UIAxes_Airfoil, 'Airfoil Geometry');
xlabel(app.UIAxes_Airfoil, 'x/c');
ylabel(app.UIAxes_Airfoil, 'y/c');

% 2. Standardize Lift Coefficient Axes
app.UIAxes_Cl.XLim = [0 20];
app.UIAxes_Cl.XTick = 0:5:20; % Clean intervals of 5 degrees
app.UIAxes_Cl.XGrid = 'on';
app.UIAxes_Cl.YGrid = 'on';
title(app.UIAxes_Cl, 'Lift Coefficient (C_l)');
xlabel(app.UIAxes_Cl, '\alpha (degrees)');
ylabel(app.UIAxes_Cl, 'C_l');

% 3. Standardize Pitching Moment Axes
app.UIAxes_Cm.XLim = [0 20];
app.UIAxes_Cm.XTick = 0:5:20;
app.UIAxes_Cm.XGrid = 'on';
app.UIAxes_Cm.YGrid = 'on';
title(app.UIAxes_Cm, 'Pitching Moment (C_{m,le})');
xlabel(app.UIAxes_Cm, '\alpha (degrees)');
ylabel(app.UIAxes_Cm, 'C_{m,le}');

% 4. Standardize Pressure Coefficient Axes
app.UIAxes_Cp.XLim = [0 1];
app.UIAxes_Cp.XTick = 0:0.2:1;
app.UIAxes_Cp.YLim = [-4 4];
%app.UIAxes_Cp.YTick = -4:2:4;
app.UIAxes_Cp.YDir = 'reverse'; % Pre-invert the Y-axis
app.UIAxes_Cp.XGrid = 'on';
app.UIAxes_Cp.YGrid = 'on';
title(app.UIAxes_Cp, 'Pressure Distribution (C_p)');
xlabel(app.UIAxes_Cp, 'x/c');
ylabel(app.UIAxes_Cp, 'C_p');
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            % 1. Get User Inputs from the GUI
naca_digits = app.NACAEditField.Value;
cp_alpha = app.CpAlphaEditField.Value;

% 1. Read the input from your Edit Field 
naca_input = app.NACAEditField.Value; 

% 2. Convert to string 
if isnumeric(naca_input)
    naca_input = num2str(naca_input, '%04d');
else
    naca_input = strtrim(naca_input);
end

% 3. The Validation Check
% Check if it is exactly 4 characters AND if all characters are digits
if length(naca_input) ~= 4 || ~all(isstrprop(naca_input, 'digit'))
    
    % Trigger the UI warning box
    uialert(app.UIFigure, ...
        'Invalid value. Please enter exactly 4 digits for a standard NACA airfoil (e.g., 4609 or 0012).', ...
        'Input Error', ...
        'Icon', 'warning');
        
    return; % This halts the function so the math below it doesn't run and crash
end
% --- ANGLE OF ATTACK VALIDATION ---
% Read the AOA input (change 'AOAEditField' to your actual component name)
aoa_input = app.CpAlphaEditField.Value;

% Convert to a number if it is a string/text field
if ischar(aoa_input) || isstring(aoa_input)
    aoa_val = str2double(aoa_input);
else
    aoa_val = aoa_input; % It is already a numeric float
end

% Check if it is Not-a-Number (NaN) OR outside the 0 to 20 range
if isnan(aoa_val) || aoa_val < 0 || aoa_val > 20
    
    % Trigger the UI warning box
    uialert(app.UIFigure, ...
        'Invalid Angle of Attack. Please enter a value between 0 and 20 degrees.', ...
        'Input Error', ...
        'Icon', 'warning');
        
    return; % Halts the function
end


%//
% 2. Extract Airfoil Parameters
M = str2double(naca_digits(1)) / 100;      
P = str2double(naca_digits(2)) / 10;       
XX = str2double(naca_digits(3:4)) / 100;   

% 3. Generate Coordinates & Airfoil Shape
x = linspace(0, 1, 101)';
yc = zeros(size(x));
dyc_dx = zeros(size(x));

if P > 0 
    front = (x <= P); back = (x > P);
    yc(front) = (M / P^2) * (2 * P * x(front) - x(front).^2);
    yc(back) = (M / (1 - P)^2) * (1 - 2 * P + 2 * P * x(back) - x(back).^2);
    dyc_dx(front) = (2 * M / P^2) * (P - x(front));
    dyc_dx(back) = (2 * M / (1 - P)^2) * (P - x(back));
end

a0 = 0.2969; a1 = -0.126; a2 = -0.3516; a3 = 0.2843; a4 = -0.1036;
yt = (XX / 0.2) * (a0 * sqrt(x) + a1 * x + a2 * x.^2 + a3 * x.^3 + a4 * x.^4);

theta_surf = atan(dyc_dx);
xu = x - yt .* sin(theta_surf); yu = yc + yt .* cos(theta_surf);
xl = x + yt .* sin(theta_surf); yl = yc - yt .* cos(theta_surf);

% 4. Thin Airfoil Theory Coefficients
theta_G = acos(1 - 2 * x);
w1 = -(1/pi) * trapz(theta_G, dyc_dx); 
A1 = (2/pi) * trapz(theta_G, dyc_dx .* cos(theta_G));
A2 = (2/pi) * trapz(theta_G, dyc_dx .* cos(2 * theta_G));

alpha_deg = 0:1:20;
alpha_rad = deg2rad(alpha_deg);
A0 = alpha_rad + w1;

cl = 2 * pi * (A0 + 0.5 * A1);
cm_le = -(0.5 * pi) * (A0 + A1 - 0.5 * A2);

% 5. Pressure Coefficient 
theta_cp = linspace(eps, pi-eps, 100)'; 
x_cp = 0.5 * (1 - cos(theta_cp)); 

A0_cp = deg2rad(cp_alpha) + w1; 
Cp_u = -2 * ((A0_cp + A0_cp * cos(theta_cp)) ./ sin(theta_cp)) - 2 * A1 * sin(theta_cp) - 2 * A2 * sin(2 * theta_cp);
Cp_l =  2 * ((A0_cp + A0_cp * cos(theta_cp)) ./ sin(theta_cp)) + 2 * A1 * sin(theta_cp) + 2 * A2 * sin(2 * theta_cp);

% 6. Plotting onto GUI Axes
% Airfoil Shape
cla(app.UIAxes_Airfoil);
plot(app.UIAxes_Airfoil, x, yc, 'r--', 'LineWidth', 1.5);
hold(app.UIAxes_Airfoil, 'on');
plot(app.UIAxes_Airfoil, xu, yu, 'k', 'LineWidth', 1.5);
plot(app.UIAxes_Airfoil, xl, yl, 'k', 'LineWidth', 1.5);
hold(app.UIAxes_Airfoil, 'off');
app.UIAxes_Airfoil.XLim = [0 1];
app.UIAxes_Airfoil.DataAspectRatio = [1 1 1];
grid(app.UIAxes_Airfoil, 'on');
title(app.UIAxes_Airfoil, ['Airfoil Shape NACA ' naca_digits]);
legend(app.UIAxes_Airfoil,'Camber Line', 'Airfoil Boundary', 'Location','northeast')

% Lift Plot
cla(app.UIAxes_Cl);
plot(app.UIAxes_Cl, alpha_deg, cl, 'b-', 'LineWidth', 1.5);
grid(app.UIAxes_Cl, 'on');
title(app.UIAxes_Cl, 'C_l vs \alpha');
xlabel(app.UIAxes_Cl, '\alpha (degrees)');
ylabel(app.UIAxes_Cl, 'C_l');

% Moment Plot
cla(app.UIAxes_Cm);
plot(app.UIAxes_Cm, alpha_deg, cm_le, 'm-', 'LineWidth', 1.5);
grid(app.UIAxes_Cm, 'on');
title(app.UIAxes_Cm, 'C_{m,le} vs \alpha');
xlabel(app.UIAxes_Cm, '\alpha (degrees)');
ylabel(app.UIAxes_Cm, 'C_{m,le}');

%Pressure Coefficient Plot
cla(app.UIAxes_Cp);
plot(app.UIAxes_Cp, x_cp, Cp_l, 'b', 'LineWidth', 1.5);
hold(app.UIAxes_Cp, 'on');
plot(app.UIAxes_Cp, x_cp, Cp_u, 'r', 'LineWidth', 1.5);
yline(app.UIAxes_Cp, 1, 'k--', 'LineWidth', 1.5, 'Alpha', 0.5);
hold(app.UIAxes_Cp, 'off');
app.UIAxes_Cp.YDir = 'reverse';
app.UIAxes_Cp.YLim = [-4 4];
grid(app.UIAxes_Cp, 'on');
title(app.UIAxes_Cp, ['Pressure Distribution at \alpha = ' num2str(cp_alpha) '^\circ']);
xlabel(app.UIAxes_Cp, 'x/c');
ylabel(app.UIAxes_Cp, 'C_p');
legend(app.UIAxes_Cp, 'Lower Surface', 'Upper Surface', 'C_p = 1 Limit', 'Location', 'best');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes_Cm = uiaxes(app.GridLayout);
            title(app.UIAxes_Cm, 'Title')
            xlabel(app.UIAxes_Cm, 'X')
            ylabel(app.UIAxes_Cm, 'Y')
            zlabel(app.UIAxes_Cm, 'Z')
            app.UIAxes_Cp = uiaxes(app.GridLayout);
            title(app.UIAxes_Cp, 'Title')
            xlabel(app.UIAxes_Cp, 'X')
            ylabel(app.UIAxes_Cp, 'Y')
            zlabel(app.UIAxes_Cp, 'Z')
            app.UIAxes_Cl = uiaxes(app.GridLayout);
            title(app.UIAxes_Cl, 'Title')
            xlabel(app.UIAxes_Cl, 'X')
            ylabel(app.UIAxes_Cl, 'Y')
            zlabel(app.UIAxes_Cl, 'Z')
            app.UIAxes_Airfoil = uiaxes(app.GridLayout);
            title(app.UIAxes_Airfoil, 'Title')
            xlabel(app.UIAxes_Airfoil, 'X')
            ylabel(app.UIAxes_Airfoil, 'Y')
            zlabel(app.UIAxes_Airfoil, 'Z')
            app.NACA4DigitsEditFieldLabel = uilabel(app.GridLayout);
            app.NACA4DigitsEditFieldLabel.Text = 'NACA 4 Digits';
            app.CpAngleofAttackEditFieldLabel = uilabel(app.GridLayout);
            app.CpAngleofAttackEditFieldLabel.Text = 'Cp Angle of Attack';
            app.AnalyzeButton = uibutton(app.GridLayout, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze Airfoil';
            app.CpAlphaEditField = uieditfield(app.GridLayout, 'numeric');
            app.CpAlphaEditField.Value = 5;
            app.NACAEditField = uieditfield(app.GridLayout, 'text');
            app.NACAEditField.Value = '4609';
            app.InputAOAAngleofAttackforthePressureDistributionLabel = uilabel(app.GridLayout);
            app.InputAOAAngleofAttackforthePressureDistributionLabel.Text = 'Input AOA (Angle of Attack) for the Pressure Distribution';
            app.InputDesiredNACA4digitAirfoilLabel = uilabel(app.GridLayout);
            app.InputDesiredNACA4digitAirfoilLabel.Text = 'Input Desired NACA 4 digit Airfoil';
            app.AirfoilAnalysisusingThinAirfoilTheoryAuthorDanialMoghadamLabel = uilabel(app.GridLayout);
            app.AirfoilAnalysisusingThinAirfoilTheoryAuthorDanialMoghadamLabel.Text = 'Airfoil Analysis using Thin Airfoil Theory | Author: Danial Moghadam ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_NACA_4Digit_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end